classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        RunDemoResumeTaskButton   matlab.ui.control.Button
        eStopSwitchLabel          matlab.ui.control.Label
        eStopSwitch               matlab.ui.control.ToggleSwitch
        Lamp                      matlab.ui.control.Lamp
        PlotTheEnvironmentButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot;
        box;
    end
    
    methods (Access = public)
        
        function results = getEstopState(app)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On')
                results = 1;
            else
                results = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunDemoResumeTaskButton
        function RunDemoResumeTaskButtonPushed(app, event)
            NewMain(app.robot,app.box,app);
        end

        % Value changed function: eStopSwitch
        function eStopSwitchValueChanged(app, event)
            value = app.eStopSwitch.Value;
            if strcmp(value,'On') 
                app.Lamp.Color = 'r';
            else
                app.Lamp.Color = 'g';
            end
            
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            robot = DensoVS060(false,transl(0,0,0),'denso')
        end

        % Button pushed function: PlotTheEnvironmentButton
        function PlotTheEnvironmentButtonPushed(app, event)
            [app.robot,app.box] = EnvironmentSetup;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.RunDemoResumeTaskButton = uibutton(app.Panel, 'push');
            app.RunDemoResumeTaskButton.ButtonPushedFcn = createCallbackFcn(app, @RunDemoResumeTaskButtonPushed, true);
            app.RunDemoResumeTaskButton.Text = 'Run Demo/ Resume Task ';
            app.eStopSwitchLabel = uilabel(app.Panel);
            app.eStopSwitchLabel.Text = 'eStop';
            app.eStopSwitch = uiswitch(app.Panel, 'toggle');
            app.eStopSwitch.ValueChangedFcn = createCallbackFcn(app, @eStopSwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.PlotTheEnvironmentButton = uibutton(app.Panel, 'push');
            app.PlotTheEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTheEnvironmentButtonPushed, true);
            app.PlotTheEnvironmentButton.Text = 'Plot The Environment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end